classdef SYTHESIZERalusin < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        FrequencySlider        matlab.ui.control.Slider
        FrequencySliderLabel   matlab.ui.control.Label
        VolumeSlider           matlab.ui.control.Slider
        VolumeSliderLabel      matlab.ui.control.Label
        DistortionSlider       matlab.ui.control.Slider
        DistortionSliderLabel  matlab.ui.control.Label
        CenterPanel            matlab.ui.container.Panel
        EditFieldR             matlab.ui.control.NumericEditField
        EditFieldS             matlab.ui.control.NumericEditField
        EditFieldD             matlab.ui.control.NumericEditField
        EditFieldA             matlab.ui.control.NumericEditField
        ReleaseLabel           matlab.ui.control.Label
        SustainLabel           matlab.ui.control.Label
        DecayLabel             matlab.ui.control.Label
        AttackLabel            matlab.ui.control.Label
        Release                matlab.ui.control.Gauge
        Sustain                matlab.ui.control.Gauge
        Decay                  matlab.ui.control.Gauge
        Attack                 matlab.ui.control.Gauge
        ADSRLabel              matlab.ui.control.Label
        SquareButton           matlab.ui.control.Button
        SawButton              matlab.ui.control.Button
        SineButton             matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
        RightPanel             matlab.ui.container.Panel
        MonoStereoKnob         matlab.ui.control.Knob
        MonoStereoKnobLabel    matlab.ui.control.Label
        ContextMenu            matlab.ui.container.ContextMenu
        Menu                   matlab.ui.container.Menu
        Menu2                  matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SineButton
        function SineButtonPushed(app, event)
            fs = 44100;
            t = (0:0.01:2*pi);
            SineWave = sin(2*pi*t);
            plot(app.UIAxes,SineWave);
        end

        % Button pushed function: SawButton
        function SawButtonPushed(app, event)
            t = (0:0.01:2*pi);
            SawWave = sawtooth(t);
            plot(app.UIAxes,SawWave);
        end

        % Button pushed function: SquareButton
        function SquareButtonPushed(app, event)
            t = (0:0.01:2*pi);
            SquareWave = square(t);
            plot(app.UIAxes,SquareWave);
        end

        % Value changed function: EditFieldA
        function EditFieldAValueChanged(app, event)
            value = app.EditFieldA.Value;
            app.Attack.Value = value;
            fs=44100; 
            t=(0:0.01:2*pi);
            SineWave = sin(2*pi*t);
            y=SineWave;
            A = linspace(0, 0.9, (length(y)*app.Attack.Value));     
            D = linspace(0.9, 0.7,(length(y)*app.Decay.Value));  
            S = linspace(0.7, 0.7,(length(y)*app.Sustain.Value));    
            R = linspace(0.7, 0,(length(y)*app.Release.Value));     
            ADSR = [A D S R] ;
            x = zeros(size(y));
            x(1:length(ADSR)) = ADSR;
            tone=y.* x; 
            plot(app.UIAxes,tone);
        end

        % Value changed function: EditFieldD
        function EditFieldDValueChanged(app, event)
            value = app.EditFieldD.Value;
            app.Decay.Value = value;
            fs=44100; 
            t=(0:0.01:2*pi);
            SineWave = sin(2*pi*t);
            y=SineWave;
            A = linspace(0, 0.9, (length(y)*app.Attack.Value));     
            D = linspace(0.9, 0.7,(length(y)*app.Decay.Value));  
            S = linspace(0.7, 0.7,(length(y)*app.Sustain.Value));    
            R = linspace(0.7, 0,(length(y)*app.Release.Value));     
            ADSR = [A D S R] ;
            x = zeros(size(y));
            x(1:length(ADSR)) = ADSR;
            tone=y.* x; 
            plot(app.UIAxes,tone);
        end

        % Value changed function: EditFieldS
        function EditFieldSValueChanged(app, event)
            value = app.EditFieldS.Value;
            app.Sustain.Value = value;
            fs=44100; 
            t=(0:0.01:2*pi);
            SineWave = sin(2*pi*t);
            y=SineWave;
            A = linspace(0, 0.9, (length(y)*app.Attack.Value));     
            D = linspace(0.9, 0.7,(length(y)*app.Decay.Value));  
            S = linspace(0.7, 0.7,(length(y)*app.Sustain.Value));    
            R = linspace(0.7, 0,(length(y)*app.Release.Value));     
            ADSR = [A D S R] ;
            x = zeros(size(y));
            x(1:length(ADSR)) = ADSR;
            tone=y.* x; 
            plot(app.UIAxes,tone);
        end

        % Value changed function: EditFieldR
        function EditFieldRValueChanged(app, event)
            value = app.EditFieldR.Value;
            app.Release.Value = value;
            fs=44100;
            t=(0:0.01:2*pi);
            SineWave = sin(2*pi*t);
            y=SineWave;
            A = linspace(0, 0.9, (length(y)*app.Attack.Value));
            D = linspace(0.9, 0.7,(length(y)*app.Decay.Value));
            S = linspace(0.7, 0.7,(length(y)*app.Sustain.Value));
            R = linspace(0.7, 0,(length(y)*app.Release.Value));
            ADSR = [A D S R] ;
            x = zeros(size(y));
            x(1:length(ADSR)) = ADSR;
            tone=y.* x;
            plot(app.UIAxes,tone);
        end

        % Value changing function: DistortionSlider
        function DistortionSliderValueChanging(app, event)
            changingValue = event.Value;
            Drive = 5;
            fs=44100;
            t=(0:0.01:2*pi);
            SineWave = sin(2*pi*t);
            in = SineWave;
            out = (2/pi) * atan(app.DistortionSlider.Value * in);  
            plot(app.UIAxes,out)
        end

        % Value changing function: MonoStereoKnob
        function MonoStereoKnobValueChanging(app, event)
            fs=44100;
            t=(0:0.01:2*pi);
            SineWave = sin(2*pi*t);
            in =  SineWave;
            changingValue = event.Value;
            mid = (in(:,1) + in(:,2)) / 2; 
            side = (in(:,1) - in(:,2)) / 2;
            side = side * app.MonoStereoKnob.Value;          
       
           out = [mid + side, mid - side]; 
           plot(app.UIAxes,out)         
        end

        % Value changing function: FrequencySlider
        function FrequencySliderValueChanging(app, event)

        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

        end

        % Value changed function: FrequencySlider
        function FrequencySliderValueChanged(app, event)
        changingValue = event.Value;
        fs = 44100;
        t=(0:0.01:2*pi);
        SineWave = sin(2*pi*t);
        in = SineWave;
        changingValue = event.Value;

        out = highpass(in,app.FrequencySlider.Value,fs);
        plot(app.UIAxes,t,out)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 224};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DistortionSliderLabel = uilabel(app.LeftPanel);
            app.DistortionSliderLabel.Text = 'Distortion';
            app.DistortionSlider = uislider(app.LeftPanel);
            app.DistortionSlider.Limits = [0 10];
            app.DistortionSlider.MajorTicks = [0 10];
            app.DistortionSlider.MajorTickLabels = {'0%', '100%'};
            app.DistortionSlider.ValueChangingFcn = createCallbackFcn(app, @DistortionSliderValueChanging, true);
            app.VolumeSliderLabel = uilabel(app.LeftPanel);
            app.VolumeSliderLabel.Text = 'Volume';
            app.VolumeSlider = uislider(app.LeftPanel);
            app.VolumeSlider.MajorTickLabels = {'-iNF', '20', '40', '60', '80', '100'};
            app.FrequencySliderLabel = uilabel(app.LeftPanel);
            app.FrequencySliderLabel.Text = 'Frequency ';
            app.FrequencySlider = uislider(app.LeftPanel);
            app.FrequencySlider.Limits = [0 20000];
            app.FrequencySlider.MajorTicks = [1 10000 20000];
            app.FrequencySlider.MajorTickLabels = {'0', '10k', '20k'};
            app.FrequencySlider.ValueChangedFcn = createCallbackFcn(app, @FrequencySliderValueChanged, true);
            app.FrequencySlider.ValueChangingFcn = createCallbackFcn(app, @FrequencySliderValueChanging, true);
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, {'Oscillator'; ''})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.SineButton = uibutton(app.CenterPanel, 'push');
            app.SineButton.ButtonPushedFcn = createCallbackFcn(app, @SineButtonPushed, true);
            app.SineButton.Text = 'Sine';
            app.SawButton = uibutton(app.CenterPanel, 'push');
            app.SawButton.ButtonPushedFcn = createCallbackFcn(app, @SawButtonPushed, true);
            app.SawButton.Text = 'Saw';
            app.SquareButton = uibutton(app.CenterPanel, 'push');
            app.SquareButton.ButtonPushedFcn = createCallbackFcn(app, @SquareButtonPushed, true);
            app.SquareButton.Text = 'Square';
            app.ADSRLabel = uilabel(app.CenterPanel);
            app.ADSRLabel.Text = 'ADSR';
            app.Attack = uigauge(app.CenterPanel, 'circular');
            app.Attack.Limits = [0 1];
            app.Decay = uigauge(app.CenterPanel, 'circular');
            app.Decay.Limits = [0 1];
            app.Sustain = uigauge(app.CenterPanel, 'circular');
            app.Sustain.Limits = [0 1];
            app.Release = uigauge(app.CenterPanel, 'circular');
            app.Release.Limits = [0 1];
            app.AttackLabel = uilabel(app.CenterPanel);
            app.AttackLabel.Text = 'Attack';
            app.DecayLabel = uilabel(app.CenterPanel);
            app.DecayLabel.Text = 'Decay';
            app.SustainLabel = uilabel(app.CenterPanel);
            app.SustainLabel.Text = 'Sustain';
            app.ReleaseLabel = uilabel(app.CenterPanel);
            app.ReleaseLabel.Text = 'Release';
            app.EditFieldA = uieditfield(app.CenterPanel, 'numeric');
            app.EditFieldA.ValueChangedFcn = createCallbackFcn(app, @EditFieldAValueChanged, true);
            app.EditFieldD = uieditfield(app.CenterPanel, 'numeric');
            app.EditFieldD.ValueChangedFcn = createCallbackFcn(app, @EditFieldDValueChanged, true);
            app.EditFieldS = uieditfield(app.CenterPanel, 'numeric');
            app.EditFieldS.ValueChangedFcn = createCallbackFcn(app, @EditFieldSValueChanged, true);
            app.EditFieldR = uieditfield(app.CenterPanel, 'numeric');
            app.EditFieldR.ValueChangedFcn = createCallbackFcn(app, @EditFieldRValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.MonoStereoKnobLabel = uilabel(app.RightPanel);
            app.MonoStereoKnobLabel.Text = 'Mono / Stereo';
            app.MonoStereoKnob = uiknob(app.RightPanel, 'continuous');
            app.MonoStereoKnob.Limits = [0 4];
            app.MonoStereoKnob.ValueChangingFcn = createCallbackFcn(app, @MonoStereoKnobValueChanging, true);
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.LeftPanel.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SYTHESIZERalusin

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end